classdef Data_Collector_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Toolbar                      matlab.ui.container.Toolbar
        PushTool                     matlab.ui.container.toolbar.PushTool
        ClearPlotButton              matlab.ui.control.Button
        DataCollectorLabel           matlab.ui.control.Label
        MethodDropDown               matlab.ui.control.DropDown
        MethodDropDownLabel          matlab.ui.control.Label
        PLCountEditField             matlab.ui.control.NumericEditField
        PLCountEditFieldLabel        matlab.ui.control.Label
        RXCountEditField             matlab.ui.control.NumericEditField
        RXCountEditFieldLabel        matlab.ui.control.Label
        TXCountEditField             matlab.ui.control.NumericEditField
        TXCountEditFieldLabel        matlab.ui.control.Label
        StatusConsoleLabel           matlab.ui.control.Label
        CalibrationSamplesEditField  matlab.ui.control.NumericEditField
        CalibrationSamplesEditFieldLabel  matlab.ui.control.Label
        DescriptionEditField         matlab.ui.control.EditField
        DescriptionEditFieldLabel    matlab.ui.control.Label
        SampleSizeEditField          matlab.ui.control.NumericEditField
        SampleSizeEditFieldLabel     matlab.ui.control.Label
        SaveLocationEditField        matlab.ui.control.EditField
        SaveLocationEditFieldLabel   matlab.ui.control.Label
        StartDataCollectionButton    matlab.ui.control.Button
        PointLogsGauge               matlab.ui.control.NinetyDegreeGauge
        PointLogsGaugeLabel          matlab.ui.control.Label
        ObjectFileDropDown           matlab.ui.control.DropDown
        ObjectFileDropDownLabel      matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        touchData % touchData Object (See VariableList.txt for more information)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, skinObj)
            
        end

        % Drop down opening function: ObjectFileDropDown
        function ObjectFileDropDownOpening(app, event)
            % Display all the contents in the "Obj_Files" directory
            listings = dir("Obj_Files");
            itemSet = {};
            itemSet{1} = '';
            for i = 3:size(listings, 1)
                itemSet{i-1} = listings(i).name;
            end
            app.ObjectFileDropDown.Items = itemSet;
        end

        % Value changed function: ObjectFileDropDown
        function ObjectFileDropDownValueChanged(app, event)
            value = app.ObjectFileDropDown.Value;

            %Check if no selection was made
            if value == ""
                return
            end

            value = "Obj_Files/" + value;
            
            %Save the vertices and facets of the selected object and plot
            %them
            try
                [app.touchData.obj.f, app.touchData.obj.v] = read_obj(value);
            catch
                out = readObj(value);
                app.touchData.obj.f = out.f.v;
                app.touchData.obj.v = out.v;
            end

            %Plot the obj file 
            
            plotOBJ(app.UIAxes, app.touchData.obj.v, app.touchData.obj.f);
            hold(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal');
            hold(app.UIAxes, 'off');
            
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            cla(app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.ObjectFileDropDownLabel = uilabel(app.UIFigure);
            app.ObjectFileDropDownLabel.Text = 'Object File';
            app.ObjectFileDropDown = uidropdown(app.UIFigure);
            app.ObjectFileDropDown.Items = {'-'};
            app.ObjectFileDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ObjectFileDropDownOpening, true);
            app.ObjectFileDropDown.ValueChangedFcn = createCallbackFcn(app, @ObjectFileDropDownValueChanged, true);
            app.ObjectFileDropDown.Value = '-';
            app.PointLogsGaugeLabel = uilabel(app.UIFigure);
            app.PointLogsGaugeLabel.Text = 'Point Logs';
            app.PointLogsGauge = uigauge(app.UIFigure, 'ninetydegree');
            app.StartDataCollectionButton = uibutton(app.UIFigure, 'push');
            app.StartDataCollectionButton.Text = 'Start Data Collection';
            app.SaveLocationEditFieldLabel = uilabel(app.UIFigure);
            app.SaveLocationEditFieldLabel.Text = 'Save Location';
            app.SaveLocationEditField = uieditfield(app.UIFigure, 'text');
            app.SaveLocationEditField.Value = 'Data\';
            app.SampleSizeEditFieldLabel = uilabel(app.UIFigure);
            app.SampleSizeEditFieldLabel.Text = 'Sample Size';
            app.SampleSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SampleSizeEditField.Value = 50;
            app.DescriptionEditFieldLabel = uilabel(app.UIFigure);
            app.DescriptionEditFieldLabel.Text = 'Description';
            app.DescriptionEditField = uieditfield(app.UIFigure, 'text');
            app.DescriptionEditField.Value = 'Skin patch data';
            app.CalibrationSamplesEditFieldLabel = uilabel(app.UIFigure);
            app.CalibrationSamplesEditFieldLabel.Text = 'Calibration Samples';
            app.CalibrationSamplesEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalibrationSamplesEditField.Value = 50;
            app.StatusConsoleLabel = uilabel(app.UIFigure);
            app.StatusConsoleLabel.Text = 'StatusConsole';
            app.TXCountEditFieldLabel = uilabel(app.UIFigure);
            app.TXCountEditFieldLabel.Text = 'TX Count';
            app.TXCountEditField = uieditfield(app.UIFigure, 'numeric');
            app.TXCountEditField.Value = 10;
            app.RXCountEditFieldLabel = uilabel(app.UIFigure);
            app.RXCountEditFieldLabel.Text = 'RX Count';
            app.RXCountEditField = uieditfield(app.UIFigure, 'numeric');
            app.RXCountEditField.Value = 3;
            app.PLCountEditFieldLabel = uilabel(app.UIFigure);
            app.PLCountEditFieldLabel.Text = 'PL Count';
            app.PLCountEditField = uieditfield(app.UIFigure, 'numeric');
            app.PLCountEditField.Value = 50;
            app.MethodDropDownLabel = uilabel(app.UIFigure);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.UIFigure);
            app.MethodDropDown.Items = {'Random Sampling', 'Even Spacing'};
            app.MethodDropDown.Value = 'Random Sampling';
            app.DataCollectorLabel = uilabel(app.UIFigure);
            app.DataCollectorLabel.Text = 'Data Collector';
            app.ClearPlotButton = uibutton(app.UIFigure, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Data_Collector_Window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end